classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SaveandopenreportdocButton      matlab.ui.control.Button
        PlotresultButton                matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        SavetoxlsfileButton             matlab.ui.control.Button
        UploadspeedinformationPanel     matlab.ui.container.Panel
        FileSizeinMBEditField           matlab.ui.control.NumericEditField
        FileSizeinMBEditFieldLabel      matlab.ui.control.Label
        UseCustomFilesizeCheckBox       matlab.ui.control.CheckBox
        CalculateuploadspeedCheckBox    matlab.ui.control.CheckBox
        UploadspeedinMBsEditField       matlab.ui.control.NumericEditField
        UploadspeedinMBsEditFieldLabel  matlab.ui.control.Label
        ProjectinfoPanel                matlab.ui.container.Panel
        ChooseyourprojecttypeDropDown   matlab.ui.control.DropDown
        ChooseyourprojecttypeDropDownLabel  matlab.ui.control.Label
        LinesofCodeLOCEditField         matlab.ui.control.NumericEditField
        LinesofCodeLOCEditFieldLabel    matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        FindresultsButton               matlab.ui.control.Button
        WelcomeLabel                    matlab.ui.control.Label
    end

    
  properties (Access = private)
    Software
  end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: FindresultsButton
        function FindresultsButtonPushed(app, event)
                KLOC = get(app.LinesofCodeLOCEditField,'Value');
                Type = get(app.ChooseyourprojecttypeDropDown,'Value')
                switch Type
                    case 'organic'
        Type = 1;
                    case 'Embedded'
        Type = 2;
                    case 'Semi-dettached'
        Type = 3;
    otherwise
        error('None chosen?');
                end
                isChecked2 = app.UseCustomFilesizeCheckBox.Value;
                if isChecked2 
                    Size_calc = app.FileSizeinMBEditField.Value;
                else
                        Size_calc = calculateSize(KLOC);
                end
                [effort, duration, TeamSize, KLOC] = cocomoEstimation(KLOC, Type);
                isChecked = app.CalculateuploadspeedCheckBox.Value;
                
                if isChecked
                    UPSPEED= get(app.UploadspeedinMBsEditField,'Value')
                    [uploadtime, TimMin] = calculateUploadTime(Size_calc, UPSPEED);
                else
                    TimMin = 'Not chosen'
                end
                %updating the table
                data = get(app.UITable, 'Data'); 
                data{1, 1} = effort;
                data{1, 2} = duration;
                data{1, 3} = KLOC;
                data{1, 4} = Size_calc;
                data{1, 5} = TeamSize;
                data{1,6} = TimMin;
                set(app.UITable, 'Data', data);
                %Finish updating the table
                
            %Making a structural array for printing and exporting:
            
           app.Software.effort = effort;
           app.Software.duration = duration;
           app.Software.Teamsize = TeamSize;
           app.Software.File_size = Size_calc;
           app.Software.KLOC = KLOC;
           app.Software.type = Type;
           app.Software.timestamp = datestr(now, 'yyyy-mm-dd HH:MM:SS');

        end

        % Button pushed function: PlotresultButton
        function PlotresultButtonPushed(app, event)
% Get data from the UITable
    data = get(app.UITable, 'Data');

    % Extract the File Size (column 4 of the table)
    fileSize = data{1, 4};

    % Validate fileSize is numeric and not empty
    if isnumeric(fileSize) && ~isempty(fileSize)
        ResultPlot(fileSize);  % Call your external function
    else
        uialert(app.UIFigure, 'Please calculate results first.', 'Missing Data');
    end
        end

        % Button pushed function: SavetoxlsfileButton
        function SavetoxlsfileButtonPushed(app, event)
            
            writingxls(app.Software, 'Records.xlsx');
        end

        % Button pushed function: SaveandopenreportdocButton
        function SaveandopenreportdocButtonPushed(app, event)
            opts = struct('format','pdf','outputDir','Records');
            publish('Estimation_upload.m', opts);
            winopen('Records/Estimation_upload.pdf')
        end

        % Value changed function: CalculateuploadspeedCheckBox
        function CalculateuploadspeedCheckBoxValueChanged(app, event)
            value = app.CalculateuploadspeedCheckBox.Value;
       
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.WelcomeLabel = uilabel(app.UIFigure);
            app.WelcomeLabel.Text = 'Welcome ';
            app.FindresultsButton = uibutton(app.UIFigure, 'push');
            app.FindresultsButton.ButtonPushedFcn = createCallbackFcn(app, @FindresultsButtonPushed, true);
            app.FindresultsButton.Text = 'Find results';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Effort'; 'Duration'; 'KLOC'; 'File_size'; 'TeamSize'; 'Upload speed'};
            app.UITable.RowName = {};
            app.ProjectinfoPanel = uipanel(app.UIFigure);
            app.ProjectinfoPanel.Title = 'Project info ';
            app.LinesofCodeLOCEditFieldLabel = uilabel(app.ProjectinfoPanel);
            app.LinesofCodeLOCEditFieldLabel.Text = 'Lines of Code (LOC):';
            app.LinesofCodeLOCEditField = uieditfield(app.ProjectinfoPanel, 'numeric');
            app.ChooseyourprojecttypeDropDownLabel = uilabel(app.ProjectinfoPanel);
            app.ChooseyourprojecttypeDropDownLabel.Text = 'Choose your project type';
            app.ChooseyourprojecttypeDropDown = uidropdown(app.ProjectinfoPanel);
            app.ChooseyourprojecttypeDropDown.Items = {'organic', 'Embedded', 'Semi-dettached'};
            app.ChooseyourprojecttypeDropDown.Value = 'organic';
            app.UploadspeedinformationPanel = uipanel(app.UIFigure);
            app.UploadspeedinformationPanel.Title = 'Upload speed information';
            app.UploadspeedinMBsEditFieldLabel = uilabel(app.UploadspeedinformationPanel);
            app.UploadspeedinMBsEditFieldLabel.Text = 'Upload speed in MBs';
            app.UploadspeedinMBsEditField = uieditfield(app.UploadspeedinformationPanel, 'numeric');
            app.CalculateuploadspeedCheckBox = uicheckbox(app.UploadspeedinformationPanel);
            app.CalculateuploadspeedCheckBox.ValueChangedFcn = createCallbackFcn(app, @CalculateuploadspeedCheckBoxValueChanged, true);
            app.CalculateuploadspeedCheckBox.Text = 'Calculate upload speed?';
            app.UseCustomFilesizeCheckBox = uicheckbox(app.UploadspeedinformationPanel);
            app.UseCustomFilesizeCheckBox.Text = 'Use Custom File size?';
            app.FileSizeinMBEditFieldLabel = uilabel(app.UploadspeedinformationPanel);
            app.FileSizeinMBEditFieldLabel.Text = 'File Size in MB';
            app.FileSizeinMBEditField = uieditfield(app.UploadspeedinformationPanel, 'numeric');
            app.SavetoxlsfileButton = uibutton(app.UIFigure, 'push');
            app.SavetoxlsfileButton.ButtonPushedFcn = createCallbackFcn(app, @SavetoxlsfileButtonPushed, true);
            app.SavetoxlsfileButton.Text = 'Save to xls file';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'A simple Calculator to aid software engineers with estimation  using COCOMO';
            app.PlotresultButton = uibutton(app.UIFigure, 'push');
            app.PlotresultButton.ButtonPushedFcn = createCallbackFcn(app, @PlotresultButtonPushed, true);
            app.PlotresultButton.Text = 'Plot result';
            app.SaveandopenreportdocButton = uibutton(app.UIFigure, 'push');
            app.SaveandopenreportdocButton.ButtonPushedFcn = createCallbackFcn(app, @SaveandopenreportdocButtonPushed, true);
            app.SaveandopenreportdocButton.Text = 'Save and open report doc';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end